classdef Interfazdatos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        desvcol                        matlab.ui.control.NumericEditField
        IMCEditField_2Label            matlab.ui.control.Label
        desvpres                       matlab.ui.control.NumericEditField
        mediacol                       matlab.ui.control.NumericEditField
        COLESTEROLEditFieldLabel       matlab.ui.control.Label
        desvimc                        matlab.ui.control.NumericEditField
        DesviacinEditFieldLabel        matlab.ui.control.Label
        mediapres                      matlab.ui.control.NumericEditField
        PRESIONEditFieldLabel          matlab.ui.control.Label
        mediaimc                       matlab.ui.control.NumericEditField
        MediaEditFieldLabel            matlab.ui.control.Label
        MediaydesviacinButton          matlab.ui.control.Button
        PresinArterialEditField        matlab.ui.control.NumericEditField
        PresinArterialEditFieldLabel   matlab.ui.control.Label
        GlucosavspresionButton         matlab.ui.control.Button
        ColesterolvsIMCButton          matlab.ui.control.Button
        GraficadebarrasButton          matlab.ui.control.Button
        EnfermedadcardiovascularLabel  matlab.ui.control.Label
        EstadodeIMCLabel               matlab.ui.control.Label
        ActividadfsicaLabel            matlab.ui.control.Label
        FumaLabel                      matlab.ui.control.Label
        AlcoholLabel                   matlab.ui.control.Label
        INFORMACINADICIONALDELPACIENTELabel  matlab.ui.control.Label
        IDEditField                    matlab.ui.control.NumericEditField
        IDEditFieldLabel               matlab.ui.control.Label
        IMCEditField                   matlab.ui.control.NumericEditField
        IMCEditFieldLabel              matlab.ui.control.Label
        CalcularIMCyPresinButton       matlab.ui.control.Button
        grafica                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
       HeartData;
    end
    
    methods (Access = private)
        
        function preprocess(app)
    app.HeartData.age = (app.HeartData.age)/365.25;
    app.HeartData.height = app.HeartData.height / 100;
    app.HeartData.active = categorical(app.HeartData.active,[0 1], {'No realiza actividad física', 'Realiza actividad física'});
    app.HeartData.smoke = categorical(app.HeartData.smoke, [0 1], {'No fuma', 'Fuma'});
    app.HeartData.alco = categorical(app.HeartData.alco, [0,1],{'consume alcohol','No consume alcohol'});  
   
    if (app.HeartData.cardio==0)
        app.HeartData.cardio = 'sin ACV';
    else if (app.HeartData.cardio==1)
            app.HeartData.cardio = 'Presenta ACV';
            
    end
    end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load("HeartData.mat","HeartData")
            app.HeartData = HeartData;
            preprocess(app);
        end

        % Button pushed function: CalcularIMCyPresinButton
        function CalcularIMCyPresinButtonPushed(app, event)
        id = app.IDEditField.Value;
          peso = app.HeartData.weight(id);
            altura = app.HeartData.height(id);
            imc = peso/altura^2;
            app.IMCEditField.Value = imc;
             if (imc<16)
            app.EstadodeIMCLabel.Text=("delgadez severa");

            elseif (imc >= 16) && (imc <= 16.99)
             app.EstadodeIMCLabel.Text=("delgadez moderada");
             
            elseif (imc >= 17) && (imc <= 18.49)
              app.EstadodeIMCLabel.Text=("delgadez leve");

             elseif (imc >= 18.5) && (imc <= 24.99)
              app.EstadodeIMCLabel.Text=("Normal");

             elseif (imc >= 25) && (imc <= 29.99)
            app.EstadodeIMCLabel.Text=("Pre-obeso");

             elseif (imc >= 30) && (imc <= 34.99)
              app.EstadodeIMCLabel.Text=("Obesidad leve");

             elseif (imc >= 35) && (imc <= 39.99)
              app.EstadodeIMCLabel.Text=("Obesidad media");

             elseif (imc >= 40)
              app.EstadodeIMCLabel.Text=("Obesidad morbida");
             end
             
   app.ActividadfsicaLabel.Text=app.HeartData.active(id);
   app.EnfermedadcardiovascularLabel.Text=num2str(app.HeartData.cardio(id));
   app.FumaLabel.Text=app.HeartData.smoke(id);
   app.AlcoholLabel.Text=app.HeartData.alco(id);
  
            presions=app.HeartData.ap_hi(id);
            presiond=app.HeartData.ap_lo(id);
            Presion=(((presions)+(2*presiond))/(3));
            app.PresinArterialEditField.Value= Presion;
             
        end

        % Button pushed function: GraficadebarrasButton
        function GraficadebarrasButtonPushed(app, event)
            cla(app.grafica,"reset");
hombres2040 = 0;
Mujeres2040 = 0;
hombres4060 = 0;
Mujeres4060 = 0;
hombresmay = 0;
Mujeresmay = 0;


for i = 1:length(app.HeartData.age)
    if app.HeartData.cardio(i) == 1 
        if app.HeartData.age(i) > 20 && app.HeartData.age(i)<=40
            if app.HeartData.gender(i) == 1
                hombres2040 = hombres2040 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres2040 = Mujeres2040 + 1;
            end
        
        elseif app.HeartData.age(i) > 40 && app.HeartData.age(i) <= 60
            if app.HeartData.gender(i) == 1
                hombres4060 = hombres4060 + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeres4060 = Mujeres4060 + 1;
            end
        
        elseif app.HeartData.age(i) > 60 && app.HeartData.age(i) <=80

            if app.HeartData.gender(i) == 1
                hombresmay = hombresmay + 1;
            elseif app.HeartData.gender(i) == 2
                Mujeresmay = Mujeresmay + 1;
            end
        end
    end
end
x = [ "20-40 años", "40-60 años", "60-80 años"];
y = [hombres2040 Mujeres2040; hombres4060 Mujeres4060; hombresmay Mujeresmay];
    bar(app.grafica,x,y)
    set(get(app.grafica(1),'ylabel'),'string','Cantidad de personas');
    set(get(app.grafica(1),'xlabel'),'string','Edad de las personas');
    set(get(app.grafica(1),'title'),'string','Enfermedad cardiovascular');
    legend(app.grafica,'Hombres','Mujeres');
        end

        % Button pushed function: ColesterolvsIMCButton
        function ColesterolvsIMCButtonPushed(app, event)
            concardioIMC = zeros(1, length(app.HeartData.age));
        sincardioIMC = zeros(1, length(app.HeartData.age));
        concardioCol = zeros(1, length(app.HeartData.age));
        sincardioCol = zeros(1, length(app.HeartData.age));
        IMC2 = zeros(1, length(app.HeartData.age));
        
        
        for i = 1:length(app.HeartData.age)

            peso=app.HeartData.weight(i);
            altura=app.HeartData.height(i);
            IMC2(i) = peso/altura^2;

            if app.HeartData.cardio(i) == 1
                concardioIMC(i)=concardioIMC(i)+IMC2(i);
                concardioCol(i)=concardioCol(i)+ app.HeartData.cholesterol(i);
            elseif app.HeartData.cardio(i) == 0
                sincardioIMC(i)=sincardioIMC(i)+IMC2(i);
                sincardioCol(i)=sincardioCol(i)+app.HeartData.cholesterol(i);
            end
        end
        
        
        scatter(app.grafica,concardioIMC, concardioCol,'MarkerFaceColor', 'b');
        hold(app.grafica,'on');
        scatter(app.grafica,sincardioIMC, sincardioCol,'MarkerFaceColor', 'y');
        hold(app.grafica,'on');
        set(get(app.grafica(1),'xlabel'),'string','IMC');
        set(get(app.grafica(1),'ylabel'),'string','COLESTEROL');
        set(get(app.grafica(1),'title'),'string',' IMC VS COLESTEROL');
        
        legend(app.grafica,'Con enfermedad cardíaca','Sin enfermedad cardiaca');
        end

        % Button pushed function: GlucosavspresionButton
        function GlucosavspresionButtonPushed(app, event)
            concardioGlu = zeros(1, length(app.HeartData.age));
        sincardioGlu = zeros(1, length(app.HeartData.age));
        concardioPres = zeros(1, length(app.HeartData.age));
        sincardioPres = zeros(1, length(app.HeartData.age));
        PresionMedia = zeros(1, length(app.HeartData.age));

        for i = 1:length(app.HeartData.age)
            presionsis=app.HeartData.ap_hi(i);
            presiondias=app.HeartData.ap_lo(i);
            PresionMedia(i)=(((presionsis)+(2*presiondias))/(3));

            if app.HeartData.cardio(i)== 1
                concardioGlu(i)=concardioGlu(i)+app.HeartData.gluc(i);
                concardioPres(i)=concardioPres(i)+PresionMedia(i);
            elseif app.HeartData.cardio(i)== 0
                sincardioGlu(i)=sincardioGlu(i)+app.HeartData.gluc(i);
                sincardioPres(i)=sincardioPres(i)+PresionMedia(i);
            end
        end
        

        scatter(app.grafica,concardioGlu, concardioPres, 'MarkerFaceColor', 'auto');
        hold(app.grafica,'on');
        scatter(app.grafica,sincardioGlu, sincardioPres, 'MarkerFaceColor', 'auto');
        hold(app.grafica,'on');
        set(get(app.grafica(1),'xlabel'),'string','GLUCOSA');
        set(get(app.grafica(1),'ylabel'),'string','PRESION ARTERIAL MEDIA');
        set(get(app.grafica(1),'title'),'string',' GLUCOSA VS PRESION ARTERIAL');
        
        legend(app.grafica,'Con enfermedad cardíaca','Sin enfermedad cardiaca');
        end

        % Button pushed function: MediaydesviacinButton
        function MediaydesviacinButtonPushed(app, event)
            IMC2= zeros(1, length(app.HeartData.weight));
             

        for i = 1:length(app.HeartData.age)

            peso=app.HeartData.weight(i);
      
            altura=app.HeartData.height(i);
            IMC2(i) = peso/altura^2;
            
        end
        app.desvimc.Value=(std(IMC2));
        app.mediaimc.Value=(mean(IMC2));

        presionmedia = zeros(1, length(app.HeartData.ap_hi));
            
            presionsis=app.HeartData.ap_hi;
            presiondias=app.HeartData.ap_lo;
            presionmedi =(((presionsis)+(2*presiondias))/(3));


        for i = 1:length(presionmedi)
            presionsis=app.HeartData.ap_hi(i);
            presiondias=app.HeartData.ap_lo(i);
            presionmedia(i) =(((presionsis)+(2*presiondias))/(3));

        end
        app.desvpres.Value=(std(presionmedia));
        app.mediapres.Value=(mean(presionmedia));

        app.mediacol.Value=(mean(app.HeartData.cholesterol));
               app.desvcol.Value=(std(app.HeartData.cholesterol));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.grafica = uiaxes(app.UIFigure);
            title(app.grafica, 'Mujeres vs Hombres ')
            xlabel(app.grafica, 'X')
            ylabel(app.grafica, 'Y')
            zlabel(app.grafica, 'Z')
            app.CalcularIMCyPresinButton = uibutton(app.UIFigure, 'push');
            app.CalcularIMCyPresinButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularIMCyPresinButtonPushed, true);
            app.CalcularIMCyPresinButton.Text = 'Calcular IMC y Presión';
            app.IMCEditFieldLabel = uilabel(app.UIFigure);
            app.IMCEditFieldLabel.Text = 'IMC';
            app.IMCEditField = uieditfield(app.UIFigure, 'numeric');
            app.IDEditFieldLabel = uilabel(app.UIFigure);
            app.IDEditFieldLabel.Text = 'ID';
            app.IDEditField = uieditfield(app.UIFigure, 'numeric');
            app.INFORMACINADICIONALDELPACIENTELabel = uilabel(app.UIFigure);
            app.INFORMACINADICIONALDELPACIENTELabel.Text = 'INFORMACIÓN ADICIONAL DEL PACIENTE';
            app.AlcoholLabel = uilabel(app.UIFigure);
            app.AlcoholLabel.Text = 'Alcohol: ';
            app.FumaLabel = uilabel(app.UIFigure);
            app.FumaLabel.Text = 'Fuma:';
            app.ActividadfsicaLabel = uilabel(app.UIFigure);
            app.ActividadfsicaLabel.Text = 'Actividad física:';
            app.EstadodeIMCLabel = uilabel(app.UIFigure);
            app.EstadodeIMCLabel.Text = 'Estado de IMC';
            app.EnfermedadcardiovascularLabel = uilabel(app.UIFigure);
            app.EnfermedadcardiovascularLabel.Text = 'Enfermedad cardiovascular:                            ';
            app.GraficadebarrasButton = uibutton(app.UIFigure, 'push');
            app.GraficadebarrasButton.ButtonPushedFcn = createCallbackFcn(app, @GraficadebarrasButtonPushed, true);
            app.GraficadebarrasButton.Text = 'Grafica de barras';
            app.ColesterolvsIMCButton = uibutton(app.UIFigure, 'push');
            app.ColesterolvsIMCButton.ButtonPushedFcn = createCallbackFcn(app, @ColesterolvsIMCButtonPushed, true);
            app.ColesterolvsIMCButton.Text = 'Colesterol vs IMC';
            app.GlucosavspresionButton = uibutton(app.UIFigure, 'push');
            app.GlucosavspresionButton.ButtonPushedFcn = createCallbackFcn(app, @GlucosavspresionButtonPushed, true);
            app.GlucosavspresionButton.Text = 'Glucosa vs presion';
            app.PresinArterialEditFieldLabel = uilabel(app.UIFigure);
            app.PresinArterialEditFieldLabel.Text = 'Presión Arterial';
            app.PresinArterialEditField = uieditfield(app.UIFigure, 'numeric');
            app.MediaydesviacinButton = uibutton(app.UIFigure, 'push');
            app.MediaydesviacinButton.ButtonPushedFcn = createCallbackFcn(app, @MediaydesviacinButtonPushed, true);
            app.MediaydesviacinButton.Text = 'Media y desviación';
            app.MediaEditFieldLabel = uilabel(app.UIFigure);
            app.MediaEditFieldLabel.Text = 'Media';
            app.mediaimc = uieditfield(app.UIFigure, 'numeric');
            app.PRESIONEditFieldLabel = uilabel(app.UIFigure);
            app.PRESIONEditFieldLabel.Text = 'PRESION';
            app.mediapres = uieditfield(app.UIFigure, 'numeric');
            app.DesviacinEditFieldLabel = uilabel(app.UIFigure);
            app.DesviacinEditFieldLabel.Text = 'Desviación ';
            app.desvimc = uieditfield(app.UIFigure, 'numeric');
            app.COLESTEROLEditFieldLabel = uilabel(app.UIFigure);
            app.COLESTEROLEditFieldLabel.Text = 'COLESTEROL';
            app.mediacol = uieditfield(app.UIFigure, 'numeric');
            app.desvpres = uieditfield(app.UIFigure, 'numeric');
            app.IMCEditField_2Label = uilabel(app.UIFigure);
            app.IMCEditField_2Label.Text = 'IMC';
            app.desvcol = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfazdatos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end